classdef mohr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InputsPanel             matlab.ui.container.Panel
        sigmaXEditFieldLabel    matlab.ui.control.Label
        sigmaXEditField         matlab.ui.control.NumericEditField
        sigmaYEditFieldLabel    matlab.ui.control.Label
        sigmaYEditField         matlab.ui.control.NumericEditField
        tauXYEditFieldLabel     matlab.ui.control.Label
        tauXYEditField          matlab.ui.control.NumericEditField
        thetaEditFieldLabel     matlab.ui.control.Label
        thetaEditField          matlab.ui.control.NumericEditField
        SolveButton             matlab.ui.control.Button
        PlanStressesPanel       matlab.ui.container.Panel
        sigmaX1EditFieldLabel   matlab.ui.control.Label
        sigmaX1EditField        matlab.ui.control.NumericEditField
        sigmaY1EditFieldLabel   matlab.ui.control.Label
        sigmaY1EditField        matlab.ui.control.NumericEditField
        tauX1Y1EditFieldLabel   matlab.ui.control.Label
        tauX1Y1EditField        matlab.ui.control.NumericEditField
        MaxShearStressPanel     matlab.ui.container.Panel
        tauMaxEditFieldLabel    matlab.ui.control.Label
        tauMaxEditField         matlab.ui.control.NumericEditField
        thetaSEditFieldLabel    matlab.ui.control.Label
        thetaSEditField         matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        PrincipalStressesPanel  matlab.ui.container.Panel
        sigma1EditFieldLabel    matlab.ui.control.Label
        sigma1EditField         matlab.ui.control.NumericEditField
        sigma2EditFieldLabel    matlab.ui.control.Label
        sigma2EditField         matlab.ui.control.NumericEditField
        thetaP1EditFieldLabel   matlab.ui.control.Label
        thetaP1EditField        matlab.ui.control.NumericEditField
        thetaP2EditFieldLabel   matlab.ui.control.Label
        thetaP2EditField        matlab.ui.control.NumericEditField
        MohrsCircleLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
           %get inputs from textBoxs :
            sigX = app.sigmaXEditField.Value;
            sigY = app.sigmaYEditField.Value;
            tXY = app.tauXYEditField.Value;
            theta = app.thetaEditField.Value;
            
            
           %Equations :
            %PLane Eqs
            sigX1 = (sigX + sigY)/2 + ((sigX - sigY)/2)*cosd(2*theta) + tXY*sind(2*theta);
            sigY1 = (sigX + sigY)/2 - ((sigX - sigY)/2)*cosd(2*theta) - tXY*sind(2*theta);
            tX1Y1 = -1*((sigX - sigY)/2)*sind(2*theta) + tXY*cosd(2*theta);
            %sigma Average and Radius value
            sigAvg = (sigX + sigY)/2;
            R = sqrt(( ( sigX-sigY )/2 )^2 + tXY^2);
            %Principal Eqs
            sig1= sigAvg + R;
            sig2 = sigAvg - R;
            thetaP1 = (atand(2*tXY/(sigX - sigY)))/2;
            thetaP2 = thetaP1 + 90;
            %Max shear Eqs
            tauMax = R;
            thetaS1 = thetaP1 - 45;
            thetaS2 = thetaP1 + 45;
            if thetaS1 > 0
                thetaS = thetaS1;
            else
                thetaS = thetaS2;
            end


           %Diplaying outputs
            %plane stresses :
            app.sigmaX1EditField.Value = sigX1;
            app.sigmaY1EditField.Value = sigY1;
            app.tauX1Y1EditField.Value = tX1Y1;
            
            %principale stresses :
            app.sigma1EditField.Value = sig1;
            app.sigma2EditField.Value = sig2;
            app.thetaP1EditField.Value = thetaP1;
            app.thetaP2EditField.Value = thetaP2;
            
            %Max shear stresses :
            app.tauMaxEditField.Value = tauMax;
            app.thetaSEditField.Value = thetaS;


           %ploting of circle and lines (AB and DD') :
            %define centre as (sigAvg,0) and radius as R
            th = 0 : pi/50 : 2*pi;
            xunit = R*cos(th) + sigAvg;
            yunit = R*sin(th);
            tXY = tXY * -1;
            tX1Y1 = tX1Y1 * -1;
            plot(app.UIAxes,xunit,yunit, [sigX sigY],[tXY -tXY],'r',[sigX1 sigY1],[tX1Y1 -tX1Y1],'g');
            legend(app.UIAxes,'AB',"DD'");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.sigmaXEditFieldLabel = uilabel(app.InputsPanel);
            app.sigmaXEditFieldLabel.Text = {'sigmaX'; ''};
            app.sigmaXEditField = uieditfield(app.InputsPanel, 'numeric');
            app.sigmaXEditField.RoundFractionalValues = 'on';
            app.sigmaYEditFieldLabel = uilabel(app.InputsPanel);
            app.sigmaYEditFieldLabel.Text = 'sigmaY';
            app.sigmaYEditField = uieditfield(app.InputsPanel, 'numeric');
            app.sigmaYEditField.RoundFractionalValues = 'on';
            app.tauXYEditFieldLabel = uilabel(app.InputsPanel);
            app.tauXYEditFieldLabel.Text = 'tauXY';
            app.tauXYEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tauXYEditField.RoundFractionalValues = 'on';
            app.thetaEditFieldLabel = uilabel(app.InputsPanel);
            app.thetaEditFieldLabel.Text = {'theta'; ''};
            app.thetaEditField = uieditfield(app.InputsPanel, 'numeric');
            app.thetaEditField.RoundFractionalValues = 'on';
            app.SolveButton = uibutton(app.InputsPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Icon = 'pin.png';
            app.SolveButton.Text = 'Solve';
            app.PlanStressesPanel = uipanel(app.UIFigure);
            app.PlanStressesPanel.Title = 'Plan Stresses';
            app.sigmaX1EditFieldLabel = uilabel(app.PlanStressesPanel);
            app.sigmaX1EditFieldLabel.Text = 'sigmaX1';
            app.sigmaX1EditField = uieditfield(app.PlanStressesPanel, 'numeric');
            app.sigmaX1EditField.RoundFractionalValues = 'on';
            app.sigmaX1EditField.ValueDisplayFormat = '%.2f';
            app.sigmaY1EditFieldLabel = uilabel(app.PlanStressesPanel);
            app.sigmaY1EditFieldLabel.Text = 'sigmaY1';
            app.sigmaY1EditField = uieditfield(app.PlanStressesPanel, 'numeric');
            app.sigmaY1EditField.RoundFractionalValues = 'on';
            app.sigmaY1EditField.ValueDisplayFormat = '%.2f';
            app.tauX1Y1EditFieldLabel = uilabel(app.PlanStressesPanel);
            app.tauX1Y1EditFieldLabel.Text = 'tauX1Y1';
            app.tauX1Y1EditField = uieditfield(app.PlanStressesPanel, 'numeric');
            app.tauX1Y1EditField.RoundFractionalValues = 'on';
            app.tauX1Y1EditField.ValueDisplayFormat = '%.2f';
            app.MaxShearStressPanel = uipanel(app.UIFigure);
            app.MaxShearStressPanel.Title = 'Max Shear Stress';
            app.tauMaxEditFieldLabel = uilabel(app.MaxShearStressPanel);
            app.tauMaxEditFieldLabel.Text = 'tauMax';
            app.tauMaxEditField = uieditfield(app.MaxShearStressPanel, 'numeric');
            app.tauMaxEditField.RoundFractionalValues = 'on';
            app.tauMaxEditField.ValueDisplayFormat = '%.2f';
            app.thetaSEditFieldLabel = uilabel(app.MaxShearStressPanel);
            app.thetaSEditFieldLabel.Text = 'thetaS';
            app.thetaSEditField = uieditfield(app.MaxShearStressPanel, 'numeric');
            app.thetaSEditField.RoundFractionalValues = 'on';
            app.thetaSEditField.ValueDisplayFormat = '%.2f';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PrincipalStressesPanel = uipanel(app.UIFigure);
            app.PrincipalStressesPanel.Title = 'Principal Stresses';
            app.sigma1EditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma1EditFieldLabel.Text = 'sigma1';
            app.sigma1EditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma1EditField.RoundFractionalValues = 'on';
            app.sigma1EditField.ValueDisplayFormat = '%.2f';
            app.sigma2EditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma2EditFieldLabel.Text = 'sigma2';
            app.sigma2EditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma2EditField.RoundFractionalValues = 'on';
            app.sigma2EditField.ValueDisplayFormat = '%.2f';
            app.thetaP1EditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.thetaP1EditFieldLabel.Text = 'thetaP1';
            app.thetaP1EditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.thetaP1EditField.RoundFractionalValues = 'on';
            app.thetaP1EditField.ValueDisplayFormat = '%.2f';
            app.thetaP2EditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.thetaP2EditFieldLabel.Text = 'thetaP2';
            app.thetaP2EditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.thetaP2EditField.RoundFractionalValues = 'on';
            app.thetaP2EditField.ValueDisplayFormat = '%.2f';
            app.MohrsCircleLabel = uilabel(app.UIFigure);
            app.MohrsCircleLabel.Text = 'Mohr''s Circle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mohr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end